classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout3               matlab.ui.container.GridLayout
        GridLayout8               matlab.ui.container.GridLayout
        ScanButton                matlab.ui.control.Button
        GridLayout5               matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        secLabel                  matlab.ui.control.Label
        CaptureDurationEditField  matlab.ui.control.NumericEditField
        TriggerPanel              matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        TrigValueSpinner          matlab.ui.control.Spinner
        ValueSpinnerLabel         matlab.ui.control.Label
        TrigSlopeDropDown         matlab.ui.control.DropDown
        SlopeDropDownLabel        matlab.ui.control.Label
        TrigChannelDropDown       matlab.ui.control.DropDown
        ChannelDropDownLabel      matlab.ui.control.Label
        AutoCaptureButton         matlab.ui.control.Button
        SingleCaptureButton       matlab.ui.control.Button
        GridLayout4               matlab.ui.container.GridLayout
        RelaySwitch               matlab.ui.control.ToggleSwitch
        GenerateSignalTextArea    matlab.ui.control.TextArea
        GenerateSignalButton      matlab.ui.control.Button
        GridLayout2               matlab.ui.container.GridLayout
        MeasureButton             matlab.ui.control.Button
        Label_ch4                 matlab.ui.control.Label
        Label_ch3                 matlab.ui.control.Label
        Label_ch2                 matlab.ui.control.Label
        Label_ch1                 matlab.ui.control.Label
        Label_src                 matlab.ui.control.Label
        tsecLabel                 matlab.ui.control.Label
        UIAxes_ch4                matlab.ui.control.UIAxes
        UIAxes_ch3                matlab.ui.control.UIAxes
        UIAxes_ch2                matlab.ui.control.UIAxes
        UIAxes_ch1                matlab.ui.control.UIAxes
        UIAxes_src                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SensorArrayPanel
        myhardware

        measure_modes = {"Pk-Pk" "Min" "Max" "Mean" "RMS" "Frequency"}
        measure_idxs = []
    end

    methods (Access = private)

        function displayDataSlowYlim(app, t,src,ch1,ch2,ch3,ch4)
            a_src = ylim(app.UIAxes_src);
            a_ch1 = ylim(app.UIAxes_ch1);
            a_ch2 = ylim(app.UIAxes_ch2);
            a_ch3 = ylim(app.UIAxes_ch3);
            a_ch4 = ylim(app.UIAxes_ch4);

            displayData(app, t,src,ch1,ch2,ch3,ch4)

            b_src = ylim(app.UIAxes_src);
            b_ch1 = ylim(app.UIAxes_ch1);
            b_ch2 = ylim(app.UIAxes_ch2);
            b_ch3 = ylim(app.UIAxes_ch3);
            b_ch4 = ylim(app.UIAxes_ch4);

            if sum(abs(a_src - b_src)) / (a_src(2) - a_src(1)) < 0.5, ylim(app.UIAxes_src, a_src); end
            if sum(abs(a_ch1 - b_ch1)) / (a_ch1(2) - a_ch1(1)) < 0.5, ylim(app.UIAxes_ch1, a_ch1); end
            if sum(abs(a_ch2 - b_ch2)) / (a_ch2(2) - a_ch2(1)) < 0.5, ylim(app.UIAxes_ch2, a_ch2); end
            if sum(abs(a_ch3 - b_ch3)) / (a_ch3(2) - a_ch3(1)) < 0.5, ylim(app.UIAxes_ch3, a_ch3); end
            if sum(abs(a_ch4 - b_ch4)) / (a_ch4(2) - a_ch4(1)) < 0.5, ylim(app.UIAxes_ch4, a_ch4); end
        end

        function displayData(app, t,src,ch1,ch2,ch3,ch4)
            i = findTriggerStart(app, src,ch1,ch2,ch3,ch4);
            if isempty(i), i = 1; end

            t_ = t(i:end) - t(i);
            plot(app.UIAxes_src, t_, src(i:end))
            plot(app.UIAxes_ch1, t_, ch1(i:end))
            plot(app.UIAxes_ch2, t_, ch2(i:end))
            plot(app.UIAxes_ch3, t_, ch3(i:end))
            plot(app.UIAxes_ch4, t_, ch4(i:end))

            ylim(app.UIAxes_src, "auto")
            ylim(app.UIAxes_ch1, "auto")
            ylim(app.UIAxes_ch2, "auto")
            ylim(app.UIAxes_ch3, "auto")
            ylim(app.UIAxes_ch4, "auto")

            displayMeasurement(app, t,src,ch1,ch2,ch3,ch4);
        end

        function displayMeasurement(app, t,src,ch1,ch2,ch3,ch4)
            if nargin == 1
                t = []; src = []; ch1 = []; ch2 = []; ch3 = []; ch4 = [];
            end
            app.Label_src.Text = app.measure(t,src);
            app.Label_ch1.Text = app.measure(t,ch1);
            app.Label_ch2.Text = app.measure(t,ch2);
            app.Label_ch3.Text = app.measure(t,ch3);
            app.Label_ch4.Text = app.measure(t,ch4);
        end

        function text = measure(app, t,y)
            lines = {};
            for idx = app.measure_idxs
                mode = app.measure_modes{idx};
                if     strcmpi(mode, "Pk-Pk")
                    line = sprintf("%s: %f", mode, peak2peak(y));
                elseif strcmpi(mode, "min")
                    line = sprintf("%s: %f", mode, min(y));
                elseif strcmpi(mode, "max")
                    line = sprintf("%s: %f", mode, max(y));
                elseif strcmpi(mode, "mean")
                    if isempty(y), val = []; else val = mean(y); end
                    line = sprintf("%s: %f", mode, val);
                elseif strcmpi(mode, "RMS")
                    if isempty(y), val = []; else val = rms(y); end
                    line = sprintf("%s: %f", mode, val);
                elseif strcmpi(mode, "frequency")
                    mode = extractBetween(mode, 1,4);
                    if isempty(t)
                        line = sprintf("%s: ", mode);
                    else
                        fs = 1 / (t(2) - t(1));
                        line = sprintf("%s: %.2f Hz", mode, medfreq(y - mean(y), fs));
                    end
                end
                lines{end + 1} = line;
            end
            text = sprintf("%s\n", lines{:});
        end

        function start = findTriggerStart(app, src,ch1,ch2,ch3,ch4)
            channel = app.TrigChannelDropDown.Value;
            if     strcmpi(channel, 'Source'),    y = src;
            elseif strcmpi(channel, 'Channel 1'), y = ch1;
            elseif strcmpi(channel, 'Channel 2'), y = ch2;
            elseif strcmpi(channel, 'Channel 3'), y = ch3;
            elseif strcmpi(channel, 'Channel 4'), y = ch4;
            else, start = []; return;
            end

            triggerValue = app.TrigValueSpinner.Value;

            triggerSlope = lower(app.TrigSlopeDropDown.Value);
            start_candidate = [];
            if contains(triggerSlope, 'rising')
                mask = y >= triggerValue;
                mask(1) = 0;
                idx2 = find(mask);
                idx = idx2(y(idx2 - 1) < triggerValue);
                start_candidate = [start_candidate; idx(:)];
            end
            if contains(triggerSlope, 'falling')
                mask = y <= triggerValue;
                mask(1) = 0;
                idx2 = find(mask);
                idx = idx2(y(idx2 - 1) > triggerValue);
                start_candidate = [start_candidate; idx(:)];
            end
            start = min(start_candidate);
        end

        function port = askForSerialPort(app)
            port = '';
            devices = IDSerialComs();
            if ~isempty(devices)
                texts = {};
                for i = 1:size(devices,1)
                    texts{end+1} = sprintf("%s (COM%d)", devices{i,1}, devices{i,2});
                end
                [idx,tf] = listdlg("ListString",texts, "SelectionMode","single", "ListSize",[300 100], ...
                                   "PromptString","Sensor board communication serial port");
                figure(app.UIFigure)
            else
                return
            end
            
            if tf
                port = sprintf("COM%d", devices{idx,2});
            end
        end

        function enableInteractiveButtons(app, enable)
            for k = 1:length(ui)
                if isempty(ui{k})
                    continue
                end
                ui{k}.Enable = enable;
            end
        end

        function managePreferences(app, load_or_save)
            preferences = {
                "app.CaptureDurationEditField.Value" 
                "app.TrigChannelDropDown.Value" 
                "app.TrigSlopeDropDown.Value" 
                "app.TrigValueSpinner.Value" 
                "app.GenerateSignalTextArea.Value" 
                "app.RelaySwitch.Value"};

            appdataDir = winqueryreg('HKEY_CURRENT_USER', 'Software\Microsoft\Windows\CurrentVersion\Explorer\Shell Folders', 'Local AppData');
            fname = fullfile(appdataDir, 'eddy-current-tomography-data-acquisition_preferences.mat');

            if     strcmpi(load_or_save, "load")
                if isfile(fname)
                    load(fname, 'data')
                    for i = 1:length(preferences)
                        eval(sprintf("%s = data{%d};", preferences{i}, i))
                    end
                end
            elseif strcmpi(load_or_save, "save")
                for i = 1:length(preferences)
                    eval(sprintf("data{%d} = %s;", i, preferences{i}))
                end
                save(fname, 'data')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = uiprogressdlg(app.UIFigure, 'Title','Initiating', 'Indeterminate','on');
            
            app.managePreferences("load");

            TrigChannelDropDownValueChanged(app, []);
            CaptureDurationEditFieldValueChanged(app, []);
            
            app.SensorArrayPanel = SensorArrayUI(app.GridLayout3);
            app.SensorArrayPanel.Layout.Row = 3;
            app.SensorArrayPanel.Layout.Column = 1;
            
            port = app.askForSerialPort();
            try
                if isempty(port)
                    app.myhardware = MyHardware();
                    app.SensorArrayPanel.Tooltip = "Serial port not connected";
                    app.SensorArrayPanel.Enable = "off";
                    % app.ScanButton.Enable = "off";
                else
                    app.myhardware = MyHardware(port);
                    app.SensorArrayPanel.myhardware = app.myhardware;
                end
            catch err
                f = errordlg(err.message);
                uiwait(f)
                delete(app)
                return
            end

            RelaySwitchValueChanged(app, []);

            app.AutoCaptureButton.Tooltip    = sprintf("%d samples/channel/sec", app.myhardware.AnalogInputRate);
            app.SingleCaptureButton.Tooltip  = sprintf("%d samples/channel/sec", app.myhardware.AnalogInputRate);
            app.GenerateSignalButton.Tooltip = sprintf("%d samples/sec",         app.myhardware.AnalogOutputRate);

            close(d)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.managePreferences("save");
            delete(app.myhardware)
            delete(app)    
        end

        % Button pushed function: AutoCaptureButton
        function AutoCaptureButtonPushed(app, event)
            if strcmpi(app.AutoCaptureButton.Text, "Run")
                span = seconds(app.CaptureDurationEditField.Value);
                app.myhardware.AnalogInputContinuous(span, @app.displayDataSlowYlim);
                app.AutoCaptureButton.Text = "Stop";
            else
                app.myhardware.AnalogInputStop();
                app.AutoCaptureButton.Text = "Run";
            end
        end

        % Button pushed function: SingleCaptureButton
        function SingleCaptureButtonPushed(app, event)
            app.myhardware.AnalogInputStop();
            app.AutoCaptureButton.Text = "Run";

            span = seconds(app.CaptureDurationEditField.Value);            
            [t,src,ch1,ch2,ch3,ch4] = app.myhardware.AnalogInputSingle(span);
            app.displayData(t,src,ch1,ch2,ch3,ch4)
        end

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
            if strcmpi(app.GenerateSignalButton.Text, "Generate signal")
                expr = sprintf('%s\n', app.GenerateSignalTextArea.Value{:});
                try
                    app.myhardware.AnalogOutputContinuous(expr);
                catch err
                    f = errordlg(err.message);
                    uiwait(f)
                    figure(app.UIFigure)
                    return
                end
                app.GenerateSignalTextArea.Enable = "off";
                app.GenerateSignalButton.Text = "Stop signal";
            else
                app.myhardware.AnalogOutputStop();
                app.GenerateSignalTextArea.Enable = "on";
                app.GenerateSignalButton.Text = "Generate signal";
            end
        end

        % Value changed function: TrigChannelDropDown
        function TrigChannelDropDownValueChanged(app, event)
            value = app.TrigChannelDropDown.Value;
            if strcmpi(value, 'none')
                app.TrigSlopeDropDown.Enable = false;
                app.TrigValueSpinner.Enable = false;
            else
                app.TrigSlopeDropDown.Enable = true;
                app.TrigValueSpinner.Enable = true;
            end
        end

        % Value changed function: RelaySwitch
        function RelaySwitchValueChanged(app, event)
            value = app.RelaySwitch.Value;
            bool = ~strcmp(value, app.RelaySwitch.Items{1});
            app.myhardware.SwitchRelay(bool);
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            [idx,tf] = listdlg("ListString", app.measure_modes, ...
                               "InitialValue", app.measure_idxs, ...
                               "Name","Measure", "ListSize",[200 200]);
            figure(app.UIFigure)
            if ~tf, return; end

            if ~(length(app.measure_idxs) == length(idx) && all(app.measure_idxs == idx))
                app.measure_idxs = idx;
                app.displayMeasurement();
            end
        end

        % Value changed function: CaptureDurationEditField
        function CaptureDurationEditFieldValueChanged(app, event)
            lim = [0 app.CaptureDurationEditField.Value];
            xlim(app.UIAxes_src, lim)
            xlim(app.UIAxes_ch1, lim)
            xlim(app.UIAxes_ch2, lim)
            xlim(app.UIAxes_ch3, lim)
            xlim(app.UIAxes_ch4, lim)

            app.UIAxes_src.XAxis.Exponent = 0;
            app.UIAxes_ch1.XAxis.Exponent = 0;
            app.UIAxes_ch2.XAxis.Exponent = 0;
            app.UIAxes_ch3.XAxis.Exponent = 0;
            app.UIAxes_ch4.XAxis.Exponent = 0;
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.myhardware.AnalogInputStop();
            app.AutoCaptureButton.Text = "Run";
            app.myhardware.AnalogOutputStop();
            app.GenerateSignalButton.Text = "Generate signal";

            d = uiprogressdlg(app.UIFigure, 'Title','Scanning', 'Cancelable','on');

            for i = 1:16
                if d.CancelRequested
                    break
                end

                gain = 1;
                confirmation = app.myhardware.SetAllSensorAndGain(i, gain, i, gain, i, gain, i, gain);

                app.SensorArrayPanel.HighlightSensorAndGain(1, i, gain);
                app.SensorArrayPanel.HighlightSensorAndGain(2, i, gain);
                app.SensorArrayPanel.HighlightSensorAndGain(3, i, gain);
                app.SensorArrayPanel.HighlightSensorAndGain(4, i, gain);
                d.Message = sprintf("Sensor %d", i);
                d.Value = i / 16;
                pause(1)
            end
       
            close(d)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Eddy current tomography data acquisition';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes_src = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_src, 'Source', 'Interpreter', 'latex')
            app.UIAxes_ch1 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch1, 'Channel 1', 'Interpreter', 'latex')
            app.UIAxes_ch2 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch2, 'Channel 2', 'Interpreter', 'latex')
            app.UIAxes_ch3 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch3, 'Channel 3', 'Interpreter', 'latex')
            app.UIAxes_ch4 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch4, 'Channel 4', 'Interpreter', 'latex')
            app.tsecLabel = uilabel(app.GridLayout2);
            app.tsecLabel.Interpreter = 'latex';
            app.tsecLabel.Text = '$t$ (sec)';
            app.Label_src = uilabel(app.GridLayout2);
            app.Label_src.Text = '';
            app.Label_ch1 = uilabel(app.GridLayout2);
            app.Label_ch1.Text = '';
            app.Label_ch2 = uilabel(app.GridLayout2);
            app.Label_ch2.Text = '';
            app.Label_ch3 = uilabel(app.GridLayout2);
            app.Label_ch3.Text = '';
            app.Label_ch4 = uilabel(app.GridLayout2);
            app.Label_ch4.Text = '';
            app.MeasureButton = uibutton(app.GridLayout2, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Tooltip = {'Select measurements'};
            app.MeasureButton.Text = 'Measure';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GenerateSignalButton = uibutton(app.GridLayout4, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Text = 'Generate signal';
            app.GenerateSignalTextArea = uitextarea(app.GridLayout4);
            app.GenerateSignalTextArea.Tooltip = {'Waveform of the generated signal.'; '"t" is a vector of time instants'; '"y" is the output vector of the same length'};
            app.GenerateSignalTextArea.Value = {'y = sin(2*pi*1000*t)'};
            app.RelaySwitch = uiswitch(app.GridLayout4, 'toggle');
            app.RelaySwitch.Items = {'Coil 1', 'Coil 2'};
            app.RelaySwitch.ValueChangedFcn = createCallbackFcn(app, @RelaySwitchValueChanged, true);
            app.RelaySwitch.Value = 'Coil 1';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.SingleCaptureButton = uibutton(app.GridLayout5, 'push');
            app.SingleCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @SingleCaptureButtonPushed, true);
            app.SingleCaptureButton.Text = 'Single';
            app.AutoCaptureButton = uibutton(app.GridLayout5, 'push');
            app.AutoCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @AutoCaptureButtonPushed, true);
            app.AutoCaptureButton.Text = 'Run';
            app.TriggerPanel = uipanel(app.GridLayout5);
            app.TriggerPanel.Title = 'Trigger';
            app.GridLayout6 = uigridlayout(app.TriggerPanel);
            app.ChannelDropDownLabel = uilabel(app.GridLayout6);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.TrigChannelDropDown = uidropdown(app.GridLayout6);
            app.TrigChannelDropDown.Items = {'Source', 'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'None'};
            app.TrigChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @TrigChannelDropDownValueChanged, true);
            app.TrigChannelDropDown.Value = 'Source';
            app.SlopeDropDownLabel = uilabel(app.GridLayout6);
            app.SlopeDropDownLabel.Text = 'Slope';
            app.TrigSlopeDropDown = uidropdown(app.GridLayout6);
            app.TrigSlopeDropDown.Items = {'Rising', 'Falling', 'Rising or Falling'};
            app.TrigSlopeDropDown.Value = 'Rising';
            app.ValueSpinnerLabel = uilabel(app.GridLayout6);
            app.ValueSpinnerLabel.Text = 'Value';
            app.TrigValueSpinner = uispinner(app.GridLayout6);
            app.TrigValueSpinner.Limits = [-10 10];
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.GridLayout7.Tooltip = {'Duration per capture'};
            app.CaptureDurationEditField = uieditfield(app.GridLayout7, 'numeric');
            app.CaptureDurationEditField.Limits = [1e-05 100];
            app.CaptureDurationEditField.ValueChangedFcn = createCallbackFcn(app, @CaptureDurationEditFieldValueChanged, true);
            app.CaptureDurationEditField.Value = 0.01;
            app.secLabel = uilabel(app.GridLayout7);
            app.secLabel.Text = 'sec';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.ScanButton = uibutton(app.GridLayout8, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end